classdef sofview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Sofview       matlab.ui.Figure
        FileMenu      matlab.ui.container.Menu
        OpenMenu      matlab.ui.container.Menu
        HelpMenu      matlab.ui.container.Menu
        TabGroup      matlab.ui.container.TabGroup
        GeomTab       matlab.ui.container.Tab
        Panel         matlab.ui.container.Panel
        MeshTab       matlab.ui.container.Tab
        SupportsTab   matlab.ui.container.Tab
        LoadsTab      matlab.ui.container.Tab
        ResultsTab    matlab.ui.container.Tab
        UIAxes        matlab.ui.control.UIAxes
        Tree          matlab.ui.container.Tree
        Node          matlab.ui.container.TreeNode
        Node2         matlab.ui.container.TreeNode
        Node3         matlab.ui.container.TreeNode
        Node4         matlab.ui.container.TreeNode
        StatusLabel   matlab.ui.control.Label
        InfoTextArea  matlab.ui.control.TextArea
    end


    properties (Access = private)
        Model SofModel % the model managed via GUI 
    end

    methods (Access = private)
    
        function displayMessage(app, msg)
            % Display message in status area.
            app.InfoTextArea.Value = [app.InfoTextArea.Value, msg];
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Model = SofModel();
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile('*.sof');
            if isequal(file,0)
                app.displayMessage('Openning file canceled');
            else
                [status, msg] = app.Model.readConfig(fullfile(path, file)); 
                app.displayMessage(msg);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Sofview = uifigure;
            app.Sofview.Name = 'Sofview';
            app.FileMenu = uimenu(app.Sofview);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.HelpMenu = uimenu(app.Sofview);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.Sofview);
            app.GeomTab = uitab(app.TabGroup);
            app.GeomTab.Title = 'Geom';
            app.Panel = uipanel(app.GeomTab);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.SupportsTab = uitab(app.TabGroup);
            app.SupportsTab.Title = 'Supports';
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.Sofview);
            title(app.UIAxes, 'Title')
            app.Tree = uitree(app.Sofview);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.StatusLabel = uilabel(app.Sofview);
            app.StatusLabel.Enable = 'off';
            app.StatusLabel.Text = 'Status:';
            app.InfoTextArea = uitextarea(app.Sofview);
        end
    end

    methods (Access = public)

        % Construct app
        function app = sofview

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Sofview)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Sofview)
        end
    end
end